classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        outputPanel              matlab.ui.container.Panel
        no_ref                   matlab.ui.control.EditField
        peak_3Label_2            matlab.ui.control.Label
        Switch                   matlab.ui.control.ToggleSwitch
        index3                   matlab.ui.control.EditField
        peak_3Label              matlab.ui.control.Label
        index2                   matlab.ui.control.EditField
        peak_2Label              matlab.ui.control.Label
        index1                   matlab.ui.control.EditField
        peak_1Label              matlab.ui.control.Label
        data_in                  matlab.ui.control.DropDown
        data_inDropDownLabel     matlab.ui.control.Label
        win_guard                matlab.ui.control.EditField
        win_guardEditFieldLabel  matlab.ui.control.Label
        win_ref                  matlab.ui.control.EditField
        win_refEditFieldLabel    matlab.ui.control.Label
        p_fa                     matlab.ui.control.EditField
        p_faEditFieldLabel       matlab.ui.control.Label
        plott                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: data_in
        function data_inValueChanged(app, event)
            value = app.data_in.Value;
            disp(value);
            disp(app.win_guard.Value);
            disp(app.win_ref.Value);
            disp(app.p_fa.Value);
             [x data_in z]=cfar_app(app.Switch.Value,value,app.win_guard.Value,app.win_ref.Value,app.p_fa.Value);
             plot (app.plott,1:length(z),z,'r');
hold (app.plott,'on');
plot (app.plott,1:length(z),data_in(1:length(z)),'k');
hold (app.plott,'off');
app.no_ref.Value=num2str(length(x));
if (length(x)==1)
app.index1.Value=num2str(x(1));
app.index2.Value='N/A';
app.index3.Value='N/A';

else if (length(x)==2)
app.index1.Value=num2str(x(1));
app.index2.Value=num2str(x(2));
app.index3.Value='N/A';
else  if (length(x)>=3)
app.index1.Value=num2str(x(1));
app.index2.Value=num2str(x(2));
app.index3.Value=num2str(x(3));

end
end
end
        end

        % Value changed function: win_ref
        function win_refValueChanged(app, event)
            value = app.win_ref.Value;
                                     [x data_in z]=cfar_app(app.Switch.Value,app.data_in.Value,app.win_guard.Value,value,app.p_fa.Value);
             plot (app.plott,1:length(z),z,'r');
hold (app.plott,'on');
plot (app.plott,1:length(z),data_in(1:length(z)),'k');
hold (app.plott,'off');
app.no_ref.Value=num2str(length(x));
if (length(x)==1)
app.index1.Value=num2str(x(1));
app.index2.Value='N/A';
app.index3.Value='N/A';

else if (length(x)==2)
app.index1.Value=num2str(x(1));
app.index2.Value=num2str(x(2));
app.index3.Value='N/A';
else if (length(x)>=3)
app.index1.Value=num2str(x(1));
app.index2.Value=num2str(x(2));
app.index3.Value=num2str(x(3));

end
end
end
        end

        % Value changed function: win_guard
        function win_guardValueChanged(app, event)
            value = app.win_guard.Value;
                         [x data_in z]=cfar_app(app.Switch.Value,app.data_in.Value,value,app.win_ref.Value,app.p_fa.Value);
             plot (app.plott,1:length(z),z,'r');
hold (app.plott,'on');
plot (app.plott,1:length(z),data_in(1:length(z)),'k');
hold (app.plott,'off');
app.no_ref.Value=num2str(length(x));
if (length(x)==1)
app.index1.Value=num2str(x(1));
app.index2.Value='N/A';
app.index3.Value='N/A';

else if (length(x)==2)
app.index1.Value=num2str(x(1));
app.index2.Value=num2str(x(2));
app.index3.Value='N/A';
else if (length(x)>=3)
app.index1.Value=num2str(x(1));
app.index2.Value=num2str(x(2));
app.index3.Value=num2str(x(3));

end
end
end
        end

        % Value changed function: p_fa
        function p_faValueChanged(app, event)
            value = app.p_fa.Value;
                         [x data_in z]=cfar_app(app.Switch.Value,app.data_in.Value,app.win_guard.Value,app.win_ref.Value,value);
             plot (app.plott,1:length(z),z,'r');
hold (app.plott,'on');
plot (app.plott,1:length(z),data_in(1:length(z)),'k');
hold (app.plott,'off');
app.no_ref.Value=num2str(length(x));
if (length(x)==1)
app.index1.Value=num2str(x(1));
app.index2.Value='N/A';
app.index3.Value='N/A';

else if (length(x)==2)
app.index1.Value=num2str(x(1));
app.index2.Value=num2str(x(2));
app.index3.Value='N/A';
else if (length(x)>=3)
app.index1.Value=num2str(x(1));
app.index2.Value=num2str(x(2));
app.index3.Value=num2str(x(3));

end
end
end


        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
     [x data_in z]=cfar_app(app.Switch.Value,app.data_in.Value,app.win_guard.Value,app.win_ref.Value,app.p_fa.Value);
             plot (app.plott,1:length(z),z,'r');
hold (app.plott,'on');
plot (app.plott,1:length(z),data_in(1:length(z)),'k');
hold (app.plott,'off');
app.no_ref.Value=num2str(length(x));
if (length(x)==1)
app.index1.Value=num2str(x(1));
app.index2.Value='N/A';
app.index3.Value='N/A';

else if (length(x)==2)
app.index1.Value=num2str(x(1));
app.index2.Value=num2str(x(2));
app.index3.Value='N/A';
else if (length(x)>=3)
app.index1.Value=num2str(x(1));
app.index2.Value=num2str(x(2));
app.index3.Value=num2str(x(3));

end
end
end
                    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.plott = uiaxes(app.UIFigure);
            title(app.plott, 'Title')
            xlabel(app.plott, 'X')
            ylabel(app.plott, 'Y')
            zlabel(app.plott, 'Z')
            app.p_faEditFieldLabel = uilabel(app.UIFigure);
            app.p_faEditFieldLabel.Text = 'p_fa';
            app.p_fa = uieditfield(app.UIFigure, 'text');
            app.p_fa.ValueChangedFcn = createCallbackFcn(app, @p_faValueChanged, true);
            app.p_fa.Value = '.001';
            app.win_refEditFieldLabel = uilabel(app.UIFigure);
            app.win_refEditFieldLabel.Text = 'win_ref';
            app.win_ref = uieditfield(app.UIFigure, 'text');
            app.win_ref.ValueChangedFcn = createCallbackFcn(app, @win_refValueChanged, true);
            app.win_ref.Value = '16';
            app.win_guardEditFieldLabel = uilabel(app.UIFigure);
            app.win_guardEditFieldLabel.Text = 'win_guard';
            app.win_guard = uieditfield(app.UIFigure, 'text');
            app.win_guard.ValueChangedFcn = createCallbackFcn(app, @win_guardValueChanged, true);
            app.win_guard.Value = '2';
            app.data_inDropDownLabel = uilabel(app.UIFigure);
            app.data_inDropDownLabel.Text = 'data_in';
            app.data_in = uidropdown(app.UIFigure);
            app.data_in.Items = {'data_in', 'data_in0', 'data_in00', 'data_in000', 'data_in0000', 'data_in00000', 'data_in000000', 'data_in0000000', 'data_in0000001', 'data_in0000002', 'data_in0000003', 'data_in1', 'data_in2', 'data_in3', 'data_in4', 'data_in5', 'data_in6'};
            app.data_in.ValueChangedFcn = createCallbackFcn(app, @data_inValueChanged, true);
            app.data_in.Value = 'data_in';
            app.outputPanel = uipanel(app.UIFigure);
            app.outputPanel.Title = 'output';
            app.peak_1Label = uilabel(app.outputPanel);
            app.peak_1Label.Text = 'peak_1';
            app.index1 = uieditfield(app.outputPanel, 'text');
            app.peak_2Label = uilabel(app.outputPanel);
            app.peak_2Label.Text = 'peak_2';
            app.index2 = uieditfield(app.outputPanel, 'text');
            app.peak_3Label = uilabel(app.outputPanel);
            app.peak_3Label.Text = 'peak_3';
            app.index3 = uieditfield(app.outputPanel, 'text');
            app.Switch = uiswitch(app.outputPanel, 'toggle');
            app.Switch.Items = {'GOCA', 'SOCA'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'GOCA';
            app.peak_3Label_2 = uilabel(app.outputPanel);
            app.peak_3Label_2.Text = 'NO_REF';
            app.no_ref = uieditfield(app.outputPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end